classdef userInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ZdrojovedataTab                 matlab.ui.container.Tab
        ZobrazenieJednaPanel            matlab.ui.container.Panel
        DostupnesadysnimokListBoxLabel  matlab.ui.control.Label
        DostupnesadyJListBox            matlab.ui.control.ListBox
        VytvormodelJButton              matlab.ui.control.Button
        ZobrazenieViacPanel             matlab.ui.container.Panel
        DostupnesadysnimokListBox_2Label  matlab.ui.control.Label
        DostupnesadyVListBox            matlab.ui.control.ListBox
        VytvormodelVButton              matlab.ui.control.Button
        VytvormodelInterpButton         matlab.ui.control.Button
        ZdrojedatDropDownLabel          matlab.ui.control.Label
        ZdrojedatDropDown               matlab.ui.control.DropDown
        ZobrazenieTab                   matlab.ui.container.Tab
        InterpolaciaSpinnerLabel        matlab.ui.control.Label
        InterpolaciaSpinner             matlab.ui.control.Spinner
        MaskovacithresholdSliderLabel   matlab.ui.control.Label
        MaskThresholdSlider             matlab.ui.control.Slider
        VoxelovymultiplierLabel         matlab.ui.control.Label
        MaskMultiplier                  matlab.ui.control.Slider
        CenterPanel                     matlab.ui.container.Panel
        StredGroup                      matlab.ui.container.TabGroup
        ZobModTab                       matlab.ui.container.Tab
        ViewerPanel                     matlab.ui.container.Panel
        Viewer                          matlab.ui.control.UIAxes
        ZobZarTab                       matlab.ui.container.Tab
        ZobrPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ZobrAx                          matlab.ui.control.UIAxes
        ZobrCo                          matlab.ui.control.UIAxes
        ZobrSa                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        XrotSliderLabel                 matlab.ui.control.Label
        XrotSlider                      matlab.ui.control.Slider
        YrotSliderLabel                 matlab.ui.control.Label
        YrotSlider                      matlab.ui.control.Slider
        ZrotSliderLabel                 matlab.ui.control.Label
        ZrotSlider                      matlab.ui.control.Slider
        ZAPVYPButton                    matlab.ui.control.StateButton
        PrehliadaniepovrstvachLabel     matlab.ui.control.Label
        VrstvyPanel                     matlab.ui.container.Panel
        XSliderLabel                    matlab.ui.control.Label
        XSlider                         matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        YSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        ZSlider                         matlab.ui.control.Slider
        Toolbar                         matlab.ui.container.Toolbar
        loadSourceDataShortcut          matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        sourceData % vstupne data
        nahlady % Description
    end
    
    properties (Access = public)
        modelSingle % Description
        modelMultiple % Description
        zobrazenie % vsetko co sa tyka zobrazovacieho okna
        zobrazenieVlastnosti % Description
        zobrazenieViewer % Description
        zobrazenieModel % Description
    end
    
    methods (Access = private)
        
        function singleOrMultipleSwitch(app)
            if contains(lower(app.ZdrojedatDropDown.Value),'jeden')
               app.ZobrPanel.Enable='off';
               app.ZobrazenieJednaPanel.Visible='on';
               app.ZobrazenieJednaPanel.Enable='on';
               app.ZobrazenieViacPanel.Visible='off';
               app.ZobrazenieViacPanel.Enable='off';
           else
               app.ZobrPanel.Enable='on';
               app.ZobrazenieJednaPanel.Visible='off';
               app.ZobrazenieJednaPanel.Enable='off';
               app.ZobrazenieViacPanel.Visible='on';
               app.ZobrazenieViacPanel.Enable='on';
           end
        end
        
        function whichParams(app)
            tab=app.StredGroup.SelectedTab.Title;
            if contains(lower(tab),'mod')
                app.zobrazenieViewer=modelVisualizer(app.zobrazenieModel,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold);
            elseif contains(lower(tab),'zar')
                %axial
                modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold);
                %coronal
                modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold);
                %sagittal
                modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           [sourceContent,app.sourceData.Path,~]=loadSourceData(); 
           app.DostupnesadyJListBox.Items = {sourceContent(:).name};
           app.DostupnesadyVListBox.Items = {sourceContent(:).name};
           app.zobrazenieVlastnosti.sample=4;
           app.zobrazenieVlastnosti.threshold = app.MaskThresholdSlider.Value;
           app.zobrazenieVlastnosti.multiplier = app.MaskMultiplier.Value;
           app.nahlady=app.ZobrAx;
           app.InterpolaciaSpinner.Value=app.zobrazenieVlastnosti.sample;
           singleOrMultipleSwitch(app);
        end

        % Button pushed function: VytvormodelJButton
        function VytvormodelJButtonPushed(app, event)
            app.modelSingle.main=makeModelSingle(app.sourceData.Path,app.DostupnesadyJListBox.Value,app.DostupnesadyJListBox.Value);
            app.zobrazenieModel=app.modelSingle.main;
            app.zobrazenieViewer=modelVisualizer(app.zobrazenieModel,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold);
        end

        % Value changed function: InterpolaciaSpinner
        function InterpolaciaSpinnerValueChanged(app, event)
            app.zobrazenieVlastnosti.sample = app.InterpolaciaSpinner.Value;
            whichParams(app);
        end

        % Value changed function: ZdrojedatDropDown
        function ZdrojedatDropDownValueChanged(app, event)
           singleOrMultipleSwitch(app);
        end

        % Value changed function: MaskThresholdSlider
        function MaskThresholdSliderValueChanged(app, event)
            app.zobrazenieVlastnosti.threshold = app.MaskThresholdSlider.Value;
            whichParams(app);
        end

        % Value changed function: MaskMultiplier
        function MaskMultiplierValueChanged(app, event)
            app.zobrazenieVlastnosti.multiplier = app.MaskMultiplier.Value;
            whichParams(app);
        end

        % Button pushed function: VytvormodelVButton
        function VytvormodelVButtonPushed(app, event)
            if length(app.DostupnesadyVListBox.Value)~=3
                fprintf("Pre uspesne zostavenie rezu potrebujete mat vybrane tri rezy, ktorych nazvy obsahuju axial, coronal a sagittal.\nStlacte akukolvek klavesu pre pokracovanie.\n");
                pause;
            else
                [app.modelMultiple.axial,app.modelMultiple.coronal,app.modelMultiple.coronalAddress,app.modelMultiple.sagittal,app.modelMultiple.sagittalAddress] = makeModelMultiple(app.sourceData.Path,app.DostupnesadyVListBox.Value,app.DostupnesadyVListBox.Value);
%                 figure(app.ZobrAx)
                %axial
                modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold);
                %coronal
                modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold);
                %sagittal
                modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold);
            end
        end

        % Button pushed function: VytvormodelInterpButton
        function VytvormodelInterpButtonPushed(app, event)
            app.modelMultiple.main=spatialMatrixInterp(app.modelMultiple.axial,app.modelMultiple.coronal,app.modelMultiple.sagittal,app.modelMultiple.coronalAddress,app.modelMultiple.sagittalAddress,true);
            app.zobrazenieModel=app.modelMultiple.main;
            whichParams(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {221, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ZdrojovedataTab = uitab(app.TabGroup);
            app.ZdrojovedataTab.Title = 'Zdrojove data';
            app.ZobrazenieJednaPanel = uipanel(app.ZdrojovedataTab);
            app.ZobrazenieJednaPanel.Title = 'Zobrazenie z jednej sady snimok';
            app.DostupnesadysnimokListBoxLabel = uilabel(app.ZobrazenieJednaPanel);
            app.DostupnesadysnimokListBoxLabel.Text = 'Dostupne sady snimok';
            app.DostupnesadyJListBox = uilistbox(app.ZobrazenieJednaPanel);
            app.DostupnesadyJListBox.Items = {'aaa'};
            app.DostupnesadyJListBox.Value = {};
            app.VytvormodelJButton = uibutton(app.ZobrazenieJednaPanel, 'push');
            app.VytvormodelJButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelJButtonPushed, true);
            app.VytvormodelJButton.Text = 'Vytvor model';
            app.ZobrazenieViacPanel = uipanel(app.ZdrojovedataTab);
            app.ZobrazenieViacPanel.Title = 'Zobrazenie z viacerých sád snímok';
            app.DostupnesadysnimokListBox_2Label = uilabel(app.ZobrazenieViacPanel);
            app.DostupnesadysnimokListBox_2Label.Text = 'Dostupne sady snimok';
            app.DostupnesadyVListBox = uilistbox(app.ZobrazenieViacPanel);
            app.DostupnesadyVListBox.Multiselect = 'on';
            app.DostupnesadyVListBox.Value = {'Item 1'};
            app.VytvormodelVButton = uibutton(app.ZobrazenieViacPanel, 'push');
            app.VytvormodelVButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelVButtonPushed, true);
            app.VytvormodelVButton.Text = 'Vytvor modely';
            app.VytvormodelInterpButton = uibutton(app.ZobrazenieViacPanel, 'push');
            app.VytvormodelInterpButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelInterpButtonPushed, true);
            app.VytvormodelInterpButton.Text = 'Vytvor interpolovany model';
            app.ZdrojedatDropDownLabel = uilabel(app.ZdrojovedataTab);
            app.ZdrojedatDropDownLabel.Text = 'Zdroje dat';
            app.ZdrojedatDropDown = uidropdown(app.ZdrojovedataTab);
            app.ZdrojedatDropDown.Items = {'jeden', 'viacero'};
            app.ZdrojedatDropDown.ValueChangedFcn = createCallbackFcn(app, @ZdrojedatDropDownValueChanged, true);
            app.ZdrojedatDropDown.Value = 'jeden';
            app.ZobrazenieTab = uitab(app.TabGroup);
            app.ZobrazenieTab.Title = 'Zobrazenie';
            app.InterpolaciaSpinnerLabel = uilabel(app.ZobrazenieTab);
            app.InterpolaciaSpinnerLabel.Text = 'Interpolacia';
            app.InterpolaciaSpinner = uispinner(app.ZobrazenieTab);
            app.InterpolaciaSpinner.Step = 2;
            app.InterpolaciaSpinner.Limits = [1 64];
            app.InterpolaciaSpinner.ValueChangedFcn = createCallbackFcn(app, @InterpolaciaSpinnerValueChanged, true);
            app.InterpolaciaSpinner.Value = 4;
            app.MaskovacithresholdSliderLabel = uilabel(app.ZobrazenieTab);
            app.MaskovacithresholdSliderLabel.Text = 'Maskovaci threshold';
            app.MaskThresholdSlider = uislider(app.ZobrazenieTab);
            app.MaskThresholdSlider.Limits = [0.01 1];
            app.MaskThresholdSlider.MajorTicks = [0.01 0.25 0.5 0.75 1];
            app.MaskThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @MaskThresholdSliderValueChanged, true);
            app.MaskThresholdSlider.Value = 0.2;
            app.VoxelovymultiplierLabel = uilabel(app.ZobrazenieTab);
            app.VoxelovymultiplierLabel.Text = 'Voxelovy multiplier';
            app.MaskMultiplier = uislider(app.ZobrazenieTab);
            app.MaskMultiplier.Limits = [1 10];
            app.MaskMultiplier.ValueChangedFcn = createCallbackFcn(app, @MaskMultiplierValueChanged, true);
            app.MaskMultiplier.Value = 2.5;
            app.CenterPanel = uipanel(app.GridLayout);
            app.StredGroup = uitabgroup(app.CenterPanel);
            app.ZobModTab = uitab(app.StredGroup);
            app.ZobModTab.Title = '3D zobrazenie modelu';
            app.ViewerPanel = uipanel(app.ZobModTab);
            app.ViewerPanel.Title = '3D Panel';
            app.Viewer = uiaxes(app.ViewerPanel);
            title(app.Viewer, 'Title')
            xlabel(app.Viewer, 'X')
            ylabel(app.Viewer, 'Y')
            zlabel(app.Viewer, 'Z')
            app.ZobZarTab = uitab(app.StredGroup);
            app.ZobZarTab.Title = 'Zobrazenie pre zarovnanie modelov';
            app.ZobrPanel = uipanel(app.ZobZarTab);
            app.GridLayout2 = uigridlayout(app.ZobrPanel);
            app.ZobrAx = uiaxes(app.GridLayout2);
            title(app.ZobrAx, 'Title')
            xlabel(app.ZobrAx, 'X')
            ylabel(app.ZobrAx, 'Y')
            zlabel(app.ZobrAx, 'Z')
            app.ZobrCo = uiaxes(app.GridLayout2);
            title(app.ZobrCo, 'Title')
            xlabel(app.ZobrCo, 'X')
            ylabel(app.ZobrCo, 'Y')
            zlabel(app.ZobrCo, 'Z')
            app.ZobrSa = uiaxes(app.GridLayout2);
            title(app.ZobrSa, 'Title')
            xlabel(app.ZobrSa, 'X')
            ylabel(app.ZobrSa, 'Y')
            zlabel(app.ZobrSa, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.XrotSliderLabel = uilabel(app.RightPanel);
            app.XrotSliderLabel.Text = 'X rot';
            app.XrotSlider = uislider(app.RightPanel);
            app.YrotSliderLabel = uilabel(app.RightPanel);
            app.YrotSliderLabel.Text = 'Y rot';
            app.YrotSlider = uislider(app.RightPanel);
            app.ZrotSliderLabel = uilabel(app.RightPanel);
            app.ZrotSliderLabel.Text = 'Z rot';
            app.ZrotSlider = uislider(app.RightPanel);
            app.ZAPVYPButton = uibutton(app.RightPanel, 'state');
            app.ZAPVYPButton.Text = 'ZAP/VYP';
            app.PrehliadaniepovrstvachLabel = uilabel(app.RightPanel);
            app.PrehliadaniepovrstvachLabel.Text = 'Prehliadanie po vrstvach';
            app.VrstvyPanel = uipanel(app.RightPanel);
            app.VrstvyPanel.Title = 'Vrstvy';
            app.XSliderLabel = uilabel(app.VrstvyPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.VrstvyPanel);
            app.YSliderLabel = uilabel(app.VrstvyPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.VrstvyPanel);
            app.ZSliderLabel = uilabel(app.VrstvyPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.VrstvyPanel);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.loadSourceDataShortcut = uipushtool(app.Toolbar);
            app.loadSourceDataShortcut.Tooltip = {'Znovu načíta zdroje z priečinka sourceData.'};
            app.loadSourceDataShortcut.Icon = 'icon-mat-refresh.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end